classdef AcousticPlotterStartGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CombineFilesButton  matlab.ui.control.Button
        FNLabel             matlab.ui.control.Label
        ListBox             matlab.ui.control.ListBox
        LoadButton          matlab.ui.control.Button
        SelectButton        matlab.ui.control.Button
        ChooseeitherABRsorDPOAErunstoviewLabel  matlab.ui.control.Label
        holdCTRLtohighlightmultipleLabel  matlab.ui.control.Label
        onlyselectonetypeABRorDPOAELabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ftype = [];
        etype = []
        path = [];
        list = {};
        fn = [];
        data = struct;
    end
    
    methods (Access = public)

        function startfuncmaster(app)

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                       
            % keep looping to find files until conditions are met
            while 1
                [file,path] = uigetfile({'*.mat', 'Matlab files (*.mat)'; '*.arf', 'TDT files (*.arf)'}, 'Select one or more files (ARF or MAT files)', 'C:\Users\Steve\Documents\Data','MultiSelect','on');
                
                % if there is one file selected continue (a single file selection will be a char array, not a cell)
                if ~iscell(file)
                    break
                else
                    % if there is more than 1 file make sure all selected files are Matlab files
                    for i = 1:length(file)
                        if strcmp(file{i}(end-3:end), '.mat')
                            ismat(i) = 1;
                        else
                            ismat(i) = 0;
                        end
                    end
                    if all(ismat)
                        break
                    else
                        w = warndlg({'Can only selected one ARF files or one or more Matlab files',...
                            ' ', 'Current selection includes either multiple ARF files or a combination of ARF and Matlab files.',...
                            ' ', 'Select Again'});
                        waitfor(w)
                    end
                end
            end

            % catch to make sure a file was choosen, otherwise make false and determine file type
            go = 0;
            if iscell(file)
                go = 1;
                app.ftype = 'MAT';
            elseif sum(file) ~= 0
                go = 1;
                if strcmp(file(end-3:end), '.mat')
                    app.ftype = 'MAT';
                elseif strcmp(file(end-3:end), '.arf')
                    app.ftype = 'ARF';
                end
            end

            if go
                % clear list
                app.list = {};
                app.etype = {};

                if strcmp(app.ftype, 'ARF')
                    %% extract data from ARF file
                    app.data = arfread_SM(fullfile(path, file));

                    % write the experiment name at top
                    app.fn =  strrep(file, '.arf', '');
                    app.FNLabel.Text = app.fn;
                    app.path = path;

                    % make list to be propogated in ListBox by looping through each group
                    for i = 1:length(app.data.groups)

                        clearvars ID ref1 ref2 nrecs type

                        % extract info from data file for each group
                        ID = app.data.groups(i).ID;
                        ref1 = app.data.groups(i).ref1;
                        ref2 = app.data.groups(i).ref2;
                        nrecs = app.data.groups(i).nrecs;
                        if nrecs > 0
                            dat = datetime(app.data.groups(i).recs(1).grp_d, 'format', 'MMM d, yyyy');

                            % figure out if its an ABR or DPOAE file
                            % The x-axis of DPOAE files is in frequency but BioSigRx save it as dur_ms
                            % this results in a dur_ms of 97656 ms which is an
                            % unrealistic duration for an ABR (>1.5 hours) so therefor must be a DPOAE
                            if unique(round([app.data.groups(i).recs.dur_ms])) > 90000
                                if i < 10
                                    type = [num2str(0), num2str(i), ': ', 'DPOAE   ', datestr(dat, 1), '   -->   '];
                                else
                                    type = [num2str(i), ': ', 'DPOAE   ', datestr(dat, 1), '   -->   '];
                                end
                            else
                                if i < 10
                                    type = [num2str(0), num2str(i), ': ', 'ABR     ', datestr(dat, 1), '   -->   '];
                                else
                                    type = [num2str(i), ': ', 'ABR     ', datestr(dat, 1), '   -->   '];
                                end
                            end
                            app.etype{i} = type;

                            % make array
                            if ~isempty(strrep(ID, 0,'')) && ~isempty(strrep(ref1, 0,'')) && ~isempty(strrep(ref2, 0,''))
                                app.list{i} = [type, ID, ' : ', ref1, ' : ', ref2, '........recs =', num2str(nrecs)];
                            elseif ~isempty(strrep(ID, 0,'')) && ~isempty(strrep(ref1, 0,''))
                                app.list{i} = [type, ID, ' : ', ref1, '........recs =', num2str(nrecs)];
                            elseif ~isempty(strrep(ID, 0,'')) && ~isempty(strrep(ref2, 0,''))
                                app.list{i} = [type, ID, ' : ', ref2, '........recs =', num2str(nrecs)];
                            else
                                app.list{i} = [type, ID, '........recs =', num2str(nrecs)];
                            end
                        end
                    end                
                elseif strcmp(app.ftype, 'MAT')
                    %% extract data from MAT file(s)

                    % if multiple files were selected file will be a cell array, combine them here
                    if iscell(file)
                       
                        % load first data file
                        app.data = load(fullfile(path, file{1}));

                        % loop through other files and append app.details and app.thresh
                        for j = 2:length(file)

                            clearvars dat
                            datatemp = load(fullfile(path, file{j}));

%                             % make sure new files are of the same type and append
%                             if ~strcmp(unique([app.data.details.type]), unique([dat.details.type]))
%                                 warning('one or more files were of a different type (ABR/DPOAE) and were not included in combined file')
%                             else
                                app.data.details = [app.data.details, datatemp.details];
                                app.data.thresh = [app.data.thresh, datatemp.thresh];
%                             end
                        end

                        % write the experiment name at top
                        app.fn =  'Combined MAT files';
                        app.FNLabel.Text = app.fn;
                        app.path = path;

                    else

                        % load data file
                        app.data = load(fullfile(path, file));

                        % write the experiment name at top
                        app.fn =  strrep(file, '.mat', '');
                        app.FNLabel.Text = app.fn;
                        app.path = path;
                    end

                    % make list to be propogated in ListBox by looping through each group
                    for i = 1:length(app.data.details)

                        clearvars ID ref1 ref2 nrecs nrecs2 type

                        % extract info from data file for each group
                        ID = app.data.details(i).ID;
                        ref1 = app.data.details(i).ref1;
                        ref2 = app.data.details(i).ref2;
                        nrecs = app.data.details(i).nrecs;

                        % catch to make sure number of recs are proper
                        nrecs2 = 0;
                        for j = 1:length(app.data.details(i).frequencies)
                            nrecs2 = nrecs2 + length(app.data.details(i).(['Freq', num2str(j)]).levels);
                        end
                        if nrecs ~= nrecs2
                            app.data.details(i).nrecs = nrecs2;
                            nrecs = nrecs2;
                        end

                        if nrecs > 0
                            dat = app.data.details(i).date;

                            if i < 10
                                type = [num2str(0), num2str(i), ': ', app.data.details(i).type, '   ', datestr(dat, 1), '   -->   '];
                            else
                                type = [num2str(i), ': ', app.data.details(i).type, '   ', datestr(dat, 1), '   -->   '];
                            end
                            app.etype{i} = type;

                            % make array
                            if ~isempty(strrep(ID, 0,'')) && ~isempty(strrep(ref1, 0,'')) && ~isempty(strrep(ref2, 0,''))
                                app.list{i} = [type, ID, ' : ', ref1, ' : ', ref2, '........recs =', num2str(nrecs)];
                            elseif ~isempty(strrep(ID, 0,'')) && ~isempty(strrep(ref1, 0,''))
                                app.list{i} = [type, ID, ' : ', ref1, '........recs =', num2str(nrecs)];
                            elseif ~isempty(strrep(ID, 0,'')) && ~isempty(strrep(ref2, 0,''))
                                app.list{i} = [type, ID, ' : ', ref2, '........recs =', num2str(nrecs)];
                            else
                                app.list{i} = [type, ID, '........recs =', num2str(nrecs)];
                            end
                        end
                    end
                end

                % enable listbox multiselect and propogate listbox 
                app.ListBox.Multiselect = 'on';
                app.ListBox.Items = app.list;
                app.ListBox.Value = app.list;

                app.SelectButton.Enable = true;

            end

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)

            % catch to make sure something was selected
            if isempty(app.ListBox.Value)
                return
            end

            % find selected items in ListBox
            for i = 1:length(app.ListBox.Value)
                select(i) = find(contains(app.list,app.ListBox.Value{i}));
            end

            % are there ABRs, are there DPOAEs
            ABR_DPOAE = [any(contains(app.etype(select), 'abr', 'IgnoreCase', true)),...
                any(contains(app.etype(select), 'dpoae', 'IgnoreCase', true))];

            % if any are DPOAE files, throw error and make pick again
            if all(ABR_DPOAE)
                
                %display error message 
                uiwait(msgbox({'Selection included a mix of ABR and DPOAE files','Please choose only ABR or only DPOAE files'}))

            else
                
                % extract only selected data and simplify structure
                if strcmp(app.ftype, 'ARF')
                    selectData = app.data.groups(sort(select));
                elseif strcmp(app.ftype, 'MAT')
                    details = app.data.details(sort(select));
                    thresh = app.data.thresh(sort(select));
                end

                % open relevant GUI
                if ABR_DPOAE(1)
                    app1 = plotABRs;
                    type = 'ABR';
                else
                    app1 = plotDPOAEs;
                    type = 'DPOAE';
                end
                
                if strcmp(app.ftype, 'ARF')
                    % extract only selected data and simplify structure
                    selectData = app.data.groups(sort(select));
                    % create details struct, has waveform data as well to pass to next GUI
                    details = collectdetailsARF(selectData, app.fn, type);

                    startfunc(app1, app.path, app.fn, details);
                elseif strcmp(app.ftype, 'MAT')
                    % gather info
                    details = app.data.details(sort(select));
                    thresh = app.data.thresh(sort(select));

                    startfunc(app1, app.path, app.fn, details, thresh);
                end

                % close figure
                close(app.UIFigure)

            end

        end

        % Button pushed function: CombineFilesButton
        function CombineFilesButtonPushed(app, event)

            % defite what app to use
            app = CombineFiles;
            % call app
            startfunc(app);
            % wait until app has been closed to continue
            waitfor(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.onlyselectonetypeABRorDPOAELabel = uilabel(app.UIFigure);
            app.onlyselectonetypeABRorDPOAELabel.Text = '(only select one type, ABR or DPOAE)';
            app.holdCTRLtohighlightmultipleLabel = uilabel(app.UIFigure);
            app.holdCTRLtohighlightmultipleLabel.Text = '(hold CTRL to highlight multiple)';
            app.ChooseeitherABRsorDPOAErunstoviewLabel = uilabel(app.UIFigure);
            app.ChooseeitherABRsorDPOAErunstoviewLabel.Text = 'Choose either ABRs or DPOAE runs to view';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load File';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.FNLabel = uilabel(app.UIFigure);
            app.FNLabel.Text = 'Load File';
            app.CombineFilesButton = uibutton(app.UIFigure, 'push');
            app.CombineFilesButton.ButtonPushedFcn = createCallbackFcn(app, @CombineFilesButtonPushed, true);
            app.CombineFilesButton.Text = 'Open Combine Files GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcousticPlotterStartGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end