classdef CombineFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EditNameButton              matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        Tree2                       matlab.ui.container.Tree
        Tree1                       matlab.ui.container.Tree
        DeleteButton                matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        CombineandMakeNewFileLabel  matlab.ui.control.Label
    end

        
    properties (Access = public)
        data;
        file;
        group;
        groupfreq;
        groupfreqlev;
        parent2;
        used;
        type = [];
    end
    
    methods (Access = public)

        function startfunc(app)
            
            % initialize new file parent in Tree2
            app.parent2 = uitreenode(app.Tree2,"Text",'NewFile');

        end
    end

    methods (Access = private)
               
        function makeGroupChildren(app, parent, fileIDX, groupIDX)

            if ~exist('groupIDX', 'var')
                select = 1:length(squeeze(app.group(fileIDX,:)));
            else
                select = groupIDX;
            end

            for i = select %group

                % make sure group exists
                if ~isempty(app.group{fileIDX, i})

                    % extract indexing information
                    txt = app.group{fileIDX, i};
                    idx = find(txt == ']');
                    idx = str2num(txt(1:idx(1)));

                    %figure out what type data this is ABR/DPOAE
                    if isempty(app.Tree2.Children.Children) 
                        if contains(txt, 'ABR')
                            app.type = 'ABR';
                        elseif contains(txt, 'DPOAE')
                            app.type = 'DPOAE';
                        end
                    end

                    % catch to see if file types are the same ABR/DPOAE
                    if contains(txt, app.type)

                        % add child1 node to tree
                        child1 = uitreenode(parent,"Text",txt);    
                        nused = makeGroupFreqChildren(app, child1, fileIDX, i);

                    else
                        % catch, only open warning dialog once
                        if ~exist('w', 'var')
                            w = warndlg(['Data types are not the same, select only ', app.type]);
                        end
                    end
                end

                % if no new files were added, delete this new parent
                if exist('nused', 'var')
                    if nused == 0
                        child1.delete;
                    end
                end
            end
        end

        function nused = makeGroupFreqChildren(app, child1, fileIDX, groupIDX, groupfreqIDX)

            nused = 0; % keep track of how many were used

            % find all frequencies of current children
            if ~isempty(child1.Children)
                currentchildren = {child1.Children.Text};
                for i = 1:length(currentchildren)
                    idxp = find(currentchildren{i} == ']');
                    idxHz = find(currentchildren{i} == 'H');
                    currentfreq(i) = str2num(currentchildren{i}(idxp+1:idxHz-1));
                end
            end

            if ~exist('groupfreqIDX', 'var')
                select = 1:length(squeeze(app.groupfreq(fileIDX, groupIDX, :)));
            else
                select = groupfreqIDX;
            end

            for i = select % group frequencies

                % make sure groupfreq exists and hasn't already been used
                if ~isempty(app.groupfreq{fileIDX, groupIDX, i}) && ~app.used(fileIDX, groupIDX, i)

                    % extract indexing information
                    txt = app.groupfreq{fileIDX, groupIDX, i};
                    idx = find(txt == ']');
                    idx = str2num(txt(1:idx(1)));

                    % add child2 node to tree
                    child2 = uitreenode(child1,"Text",txt);

                    for j = 1:length(app.groupfreqlev(fileIDX, groupIDX, i, :))
                        % add child3 node to tree if exists
                        if ~isempty(app.groupfreqlev{fileIDX, groupIDX, i, j})
                            child3 = uitreenode(child2,"Text",app.groupfreqlev{fileIDX, groupIDX, i, j});
                        end
                    end

                    % update used file
                    app.used(fileIDX, groupIDX, i) = 1;
                    nused = nused + 1;

                    % move position of newly added node in tree
                    if exist('groupfreqIDX', 'var')
                        idxp = find(txt == ']');
                        idxHz = find(txt == 'H');
                        newfreq = str2num(txt(idxp+1:idxHz-1));

                        loc = find(newfreq < currentfreq, 1, 'first');
                        
                        % catch if new frequency is greater than anything in the old frequencies, if yes then loc will be empty and put new frequency at end
                        if ~isempty(loc)
                            move(child2, child1.Children(loc), 'before')
                        end
                    end
                end
            end
        end
        
        function w = changeEnable(app, en, w)
            % this function toggles the enable on all the buttons and
            % displays a "busy" message while Matlab is running

            app.LoadButton.Enable = en;
            app.DeleteButton.Enable = en;
            app.AddButton.Enable = en;
            app.EditNameButton.Enable = en;
            app.SaveButton.Enable = en;
            app.Tree1.Enable = en;
            app.Tree2.Enable = en;

            if ~en
                w = waitbar(0, 'Busy...');
            else
                close(w)
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % user input of file
            [file,path] = uigetfile('*.mat', 'Select one or more MAT files', 'C:\Users\Steve\Documents\Data', 'MultiSelect', 'on');

            w = changeEnable(app, 0);

            % make sure file and path are cells
            if ~iscell(file)
                if file == 0
                    file = {};
                else
                    file = {file};
                end
            end
            
            % catch to make sure a file was choosen, otherwise do nothing
            if ~isempty(file)

                % empty tree1 and reset vars if there were already files
                if ~isempty(app.Tree1.Children)
                    app.Tree1.Children.delete;
                    app.file = {};
                    app.group = {};
                    app.groupfreq = {};
                    app.data = [];
                    app.used = [];
                end

                count = 1;

                for fileIDX = 1:length(file) %file

                    clearvars parent child1 child2 child3

                    % load files names
                    app.file{fileIDX} = ['[', num2str(fileIDX), ',0,0] ', file{fileIDX}];

                    % add parent node to tree
                    parent = uitreenode(app.Tree1,"Text",app.file{fileIDX});

                    % extract data from file
                    app.data{fileIDX} = load(fullfile(path, file{fileIDX}));

                    for groupIDX = 1:length(app.data{fileIDX}.details) %group

                        % extract information to make text array
                        txt1 = ['[', num2str(fileIDX), ',' num2str(groupIDX), ',0] ',...
                            char(app.data{fileIDX}.details(groupIDX).date), ' - ',...
                            app.data{fileIDX}.details(groupIDX).type, ' - ',...
                            app.data{fileIDX}.details(groupIDX).ID, '; ',...
                            app.data{fileIDX}.details(groupIDX).ref1, '; ',...
                            app.data{fileIDX}.details(groupIDX).ref2, '; ',...
                            app.data{fileIDX}.details(groupIDX).notes];

                        % load group names
                        app.group{fileIDX,groupIDX} = txt1;

                        % add child1 node to tree
                        child1 = uitreenode(parent,"Text",txt1);

                        for groupfreqIDX = 1:length(app.data{fileIDX}.details(groupIDX).freqlist) % group frequencies

                            % extract information to make text array
                            txt2 = ['[', num2str(fileIDX), ',' num2str(groupIDX), ',', num2str(groupfreqIDX), '] ',...
                                app.data{fileIDX}.details(groupIDX).freqlist{groupfreqIDX}];

                            % load groupfreq names
                            app.groupfreq{fileIDX,groupIDX,groupfreqIDX} = txt2;

                            % add child2 node to tree
                            child2 = uitreenode(child1,"Text",txt2);

                            % display levels but they will have no function
                            for lev = 1:length(app.data{fileIDX}.details(groupIDX).(['Freq', num2str(groupfreqIDX)]).levels) 
                                % extract information to make text array
                                txt3 = [num2str(app.data{fileIDX}.details(groupIDX).(['Freq', num2str(groupfreqIDX)]).levels(lev)), ' dB SPL -  ',...
                                    num2str(app.data{fileIDX}.details(groupIDX).(['Freq', num2str(groupfreqIDX)]).navgs(lev)),'averages'];

                                % load groupfreq names
                                app.groupfreqlev{fileIDX,groupIDX,groupfreqIDX,lev} = txt3;

                                % add child3 node to tree
                                child3 = uitreenode(child2,"Text",txt3);

                            end

                            % matrix structure to keep track of each file desination in the tree and if it's been selected                            
                            app.used(fileIDX,groupIDX,groupfreqIDX) = 1; % do inverse initially and we'll switch this later

                            count = count+1;
                        end
                    end

                    expand(parent)

                end

                % make any empty positions nan and then change all to 0;
                app.used(app.used == 0) = nan;
                app.used(app.used == 1) = 0;

            end

            app.Tree1.Multiselect = 'off';
            changeEnable(app, 1, w);

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            if ~isempty(app.Tree1.Children) && ~isempty(app.Tree1.SelectedNodes)

                w = changeEnable(app, 0);

                % extract indexing information
                txt = app.Tree1.SelectedNodes.Text;
                idx = find(txt == ']');
                idx = str2num(txt(1:idx(1)));

                if sum(idx == 0) == 0 %% add frequency

                    if ~isempty(app.Tree2.SelectedNodes) % catch to make sure something is selected
                        if strcmp(app.Tree2.SelectedNodes.Parent.Text, 'NewFile') % catch to make sure its at the group level
                            if ~app.used(idx(1), idx(2), idx(3)) % make sure selected frequency isn't already used
                                makeGroupFreqChildren(app, app.Tree2.SelectedNodes, idx(1), idx(2), idx(3));
                            end
                        else
                            warndlg('Select a group to add frequency to')
                        end
                    else
                        warndlg('Select a group to add frequency to')
                    end
                    disp(' ')

                elseif sum(idx == 0) == 1 % add group

                    % add children to Tree2
                    makeGroupChildren(app, app.parent2, idx(1), idx(2))
                    expand(app.parent2)


                elseif sum(idx == 0) == 2 % add file

                    % add children to Tree2
                    makeGroupChildren(app, app.parent2, idx(1))
                    expand(app.parent2)

                end

                changeEnable(app, 1, w);
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)

            if ~isempty(app.Tree2.Children.Children) && ~isempty(app.Tree2.SelectedNodes)

                w = changeEnable(app, 0);

                % if parent of new file is selected, delete everything
                if strcmp(app.Tree2.SelectedNodes.Text, 'NewFile')

                    % delete all nodes
                    app.Tree2.SelectedNodes.Children.delete

                    % update used structure
                    app.used(app.used == 1) = 0;

                else

                    % extract indexing information
                    txt = app.Tree2.SelectedNodes.Text;
                    idx = find(txt == ']');
                    idx = str2num(txt(1:idx(1)));

                    if sum(idx == 0) == 0 % remove only frequencies

                        % update used structure
                        if ~isnan(app.used(idx(1), idx(2), idx(3)))
                            app.used(idx(1), idx(2), idx(3)) = 0;
                        end

                    elseif sum(idx == 0) == 1 % remove all groups and frequencies

                        % update used structure
                        for i = 1:size(app.used,3)
                            if ~isnan(app.used(idx(1), idx(2), i))
                                app.used(idx(1), idx(2), i) = 0;
                            end
                        end
                    end

                    % Get parent
                    parent = app.Tree2.SelectedNodes.Parent;

                    % delete node
                    app.Tree2.SelectedNodes.delete

                    % check to see is parent is now empty, if yes delete
                    if isempty(parent.Children)
                        parent.delete
                    end

                end

                changeEnable(app, 1, w);
            end
        end

        % Button pushed function: EditNameButton
        function EditNameButtonPushed(app, event)

            % extract indexing information
            txt = app.Tree2.SelectedNodes.Text;
            idxp = find(txt == ']');
            idx = str2num(txt(1:idxp(1)));

            % make sure we are only editing the group
            if sum(idx == 0) == 1 

                % get info of selected
                ID = app.data{idx(1)}.details(idx(2)).ID;
                ref1 = app.data{idx(1)}.details(idx(2)).ref1;
                ref2 = app.data{idx(1)}.details(idx(2)).ref2;
                notes = app.data{idx(1)}.details(idx(2)).notes;

                % open dialog box for user to edit text
                answer = inputdlg({'ID', 'ref1', 'ref2', 'notes'}, 'Text Edit', [1 75], {ID, ref1, ref2, notes});

                if ~isempty(answer)

                    % catch if a cell is empty
                    answer(cellfun(@isempty,answer)) = {[]};

                    % update info of selected with new input
                    app.data{idx(1)}.details(idx(2)).ID = answer{1};
                    app.data{idx(1)}.details(idx(2)).ref1 = answer{2};
                    app.data{idx(1)}.details(idx(2)).ref2 = answer{3};
                    app.data{idx(1)}.details(idx(2)).notes = answer{4};

                    % update text
                    txt1 = ['[', num2str(idx(1)), ',' num2str(idx(2)), ',0] ',...
                        char(app.data{idx(1)}.details(idx(2)).date), ' - ',...
                        app.data{idx(1)}.details(idx(2)).type, ' - ',...
                        app.data{idx(1)}.details(idx(2)).ID, '; ',...
                        app.data{idx(1)}.details(idx(2)).ref1, '; ',...
                        app.data{idx(1)}.details(idx(2)).ref2, '; ',...
                        app.data{idx(1)}.details(idx(2)).notes];

                    % update group names
                    app.group{idx(1),idx(2)} = txt1;

                    % update node
                    app.Tree2.SelectedNodes.Text = txt1;
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % catch to make sure there is something selected
            if ~isempty(app.Tree2.Children.Children)
                count = 1;

                for i = 1:length(app.Tree2.Children.Children)

                    % extract indexing information
                    txt1 = app.Tree2.Children.Children(i).Text;
                    idx1 = find(txt1 == ']');
                    idx1 = str2num(txt1(1:idx1(1)));

                    %find new number of recordings
                    nrecs = 0;
                    for j = 1:length(app.data{idx1(1)}.details(idx1(2)).frequencies)
                        nrecs = nrecs + length(app.data{idx1(1)}.details(idx1(2)).(['Freq', num2str(j)]).levels);
                    end

                    % construct details with no raw data in it
                    details(count).type = app.data{idx1(1)}.details(idx1(2)).type;
                    details(count).ID = app.data{idx1(1)}.details(idx1(2)).ID;
                    details(count).ref1 = app.data{idx1(1)}.details(idx1(2)).ref1;
                    details(count).ref2 = app.data{idx1(1)}.details(idx1(2)).ref2;
                    details(count).notes = app.data{idx1(1)}.details(idx1(2)).notes;
                    details(count).originalFN = app.data{idx1(1)}.details(idx1(2)).originalFN;
                    details(count).nrecs = nrecs;
                    details(count).date = app.data{idx1(1)}.details(idx1(2)).date;
                    details(count).frequencies = [];
                    details(count).freqlist = {};

                    thresh(count).freq = [];
                    thresh(count).temp = [];
                    thresh(count).thresh = [];
                    thresh(count).user = [];

                    for j = 1:length(app.Tree2.Children.Children(i).Children)

                        % extract indexing information
                        txt2 = app.Tree2.Children.Children(i).Children(j).Text;
                        idx2 = find(txt2 == ']');
                        idx2 = str2num(txt2(1:idx2(1)));

                        % append new details with selected info
                        details(count).frequencies = [details(count).frequencies, app.data{idx2(1)}.details(idx2(2)).frequencies(idx2(3))];
                        details(count).freqlist = [details(count).freqlist, app.data{idx2(1)}.details(idx2(2)).freqlist(idx2(3))];
                        details(count).(['Freq', num2str(j)]) = app.data{idx2(1)}.details(idx2(2)).(['Freq', num2str(idx2(3))]);

                        % append new thresh with selected info
                        thresh(count).freq = [thresh(count).freq, app.data{idx2(1)}.thresh(idx2(2)).freq(idx2(3))];
                        thresh(count).temp = [thresh(count).temp, app.data{idx2(1)}.thresh(idx2(2)).temp(idx2(3))];
                        thresh(count).thresh = [thresh(count).thresh, app.data{idx2(1)}.thresh(idx2(2)).thresh(idx2(3))];
                        thresh(count).user = [thresh(count).user, app.data{idx2(1)}.thresh(idx2(2)).user(idx2(3))];

                    end
                    count = count +1;
                end

                % make date string
                [Y, M, D] = ymd(datetime('today'));
                Y = num2str(Y-2000);
                if M < 10
                    M = ['0', num2str(M)];
                else
                    M = num2str(M);
                end
                if D < 10
                    D = ['0', num2str(D)];
                else
                    D = num2str(D);
                end

                % select folder and file name to save to
                [file,path] = uiputfile([Y, '_', M, '_', D,'_Combined.mat']);

                % catch to make sure the user choose a file
                if sum(file) ~= 0

                    saveName = fullfile(path, file);

                    save(saveName, 'details', 'thresh', '-v7.3')

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CombineandMakeNewFileLabel = uilabel(app.UIFigure);
            app.CombineandMakeNewFileLabel.Text = 'Combine and Make New File';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.Tree1 = uitree(app.UIFigure);
            app.Tree2 = uitree(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.EditNameButton = uibutton(app.UIFigure, 'push');
            app.EditNameButton.ButtonPushedFcn = createCallbackFcn(app, @EditNameButtonPushed, true);
            app.EditNameButton.Text = 'Edit Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CombineFiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end