classdef MakeGroups < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        holdCTRLtohighlightmultipleLabel  matlab.ui.control.Label
        ClearGroups        matlab.ui.control.Button
        RemoveButton       matlab.ui.control.Button
        AddButton          matlab.ui.control.Button
        TypeDropDown       matlab.ui.control.DropDown
        ListBox_2          matlab.ui.control.ListBox
        DoneButton         matlab.ui.control.Button
        AssignGroupsLabel  matlab.ui.control.Label
        ListBox_1          matlab.ui.control.ListBox
    end

        
    properties (Access = public)
        groups = [];
        list = {};
        waitvalue = 1;
    end
    
    methods (Access = public)

        function startfunc2(app, list, groups)

            % load variables
            app.groups = groups;
            app.list = list;

            % propogate initial listbox, see if groups already exist
            app.ListBox_1.Items = app.list;
            if all(isnan(app.groups))
                app.ListBox_2.Items = {};
            else
                app.ListBox_2.Items = app.ListBox_1.Items(find(app.groups == 1));
            end

            % enable multiselect
            app.ListBox_1.Multiselect = 'on';
            app.ListBox_2.Multiselect = 'on';

            app.ListBox_1.Value = {};
            
        end
    end

    methods (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

            if ~isempty(app.ListBox_1.Value)

                % find which group is selected
                gr = find(contains(app.TypeDropDown.Items,app.TypeDropDown.Value));

                % find selected items in ListBox 1
                for i = 1:length(app.ListBox_1.Value)
                    ix = find(contains(app.ListBox_1.Items,app.ListBox_1.Value{i}));
                    % catch if multiiple lines exist
                    if length(ix) > 1
                        ix = ix(1);
                    end
                    selected1(i) = ix;
                end
                selected1 = sort(selected1);

                % find items in already in listbox 2
                selected2 = [];
                if ~isempty(app.ListBox_2.Items)
                    for i = 1:length(app.ListBox_2.Items)
                        ix = find(contains(app.ListBox_1.Items,app.ListBox_2.Items{i}));
                        % catch if multiiple lines exist
                        if length(ix) > 1
                            ix = ix(1);
                        end
                        selected2(i) = ix;
                    end
                end
                selected = sort(unique([selected1, selected2]));

                % rewrite listbox 1 with groups
                for i = selected
                    app.list{i}(1:4) = ['grp', num2str(gr)];
                end
                app.ListBox_1.Items = app.list;

                % deselect everything
                app.ListBox_1.Value = {};

                % rewrite listbox 2 with mice
                app.ListBox_2.Items = app.list(selected);

                % assign group
                app.groups(selected) = gr;
            end

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
                   
            if ~isempty(app.ListBox_2.Value)

                % find which group is selected
                gr = find(contains(app.TypeDropDown.Items,app.TypeDropDown.Value));

                % find selected items in ListBox, need one selected for each listbox
                for i = 1:length(app.ListBox_2.Value)
                    selected1(i) = find(contains(app.ListBox_1.Items,app.ListBox_2.Value{i}));
                    selected2(i) = find(contains(app.ListBox_2.Items,app.ListBox_2.Value{i}));
                end
                selected1 = sort(selected1);
                selected2 = sort(selected2);

                % rewrite listbox 1 with groups
                for i = selected2
                    app.list{i}(1:4) = '----';
                end
                app.ListBox_1.Items = app.list;

                % rewrite listbox 2 with mice
                orig = app.ListBox_2.Items;
                sel = ones([1, length(orig)]); sel(selected2) = 0;
                app.ListBox_2.Items = orig(find(sel));

                % deselect everything
                app.ListBox_2.Value = {};

                % assign group
                app.groups(selected2) = nan;
            end

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            % find which group is selected
            gr = find(contains(app.TypeDropDown.Items,app.TypeDropDown.Value));
            
            % find which mice to be displayed
            idx = find(app.groups == gr);

            % propogate listbox
            app.ListBox_2.Items = app.list(idx);

            % deselect everything
            app.ListBox_2.Value = {};

        end

        % Button pushed function: ClearGroups
        function ClearGroupsButtonPushed(app, event)
            
            % load variables
            app.groups = nan(size(app.groups));

            % rewrite listbox 1 with groups
            for i = 1:length(app.list)
                app.list{i}(1:4) = '----';
            end
            app.ListBox_1.Items = app.list;

            % remove everything from listbox 2
            app.ListBox_2.Items = {};

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % change the waitvalue which will trigger the waitfor() to stop waiting
            app.waitvalue = 0;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ListBox_1 = uilistbox(app.UIFigure);
            app.AssignGroupsLabel = uilabel(app.UIFigure);
            app.AssignGroupsLabel.Text = 'Assign Groups';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Group 1', 'Group 2', 'Group 3', 'Group 4', 'Group 5', 'Group 6', 'Group 7', 'Group 8', 'Group 9'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Group 1';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '>>>';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<<<';
            app.ClearGroups = uibutton(app.UIFigure, 'push');
            app.ClearGroups.ButtonPushedFcn = createCallbackFcn(app, @ClearGroupsButtonPushed, true);
            app.ClearGroups.Text = 'Clear Groups';
            app.holdCTRLtohighlightmultipleLabel = uilabel(app.UIFigure);
            app.holdCTRLtohighlightmultipleLabel.Text = '(hold CTRL to highlight multiple)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MakeGroups

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end