classdef ThreshTypeDP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextBegin     matlab.ui.control.Label
        CancelButton  matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
        TextEnd       matlab.ui.control.Label
        VarEditField  matlab.ui.control.NumericEditField
        DropDown      matlab.ui.control.DropDown
        WhatThresholdingStratagydoyouwanttouseLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        type = [];
        val = [];
    end
    
    methods (Access = public)
        
        function startfunc2(app)
            
            % makes invisible
            app.TextBegin.Visible = 0;
            app.TextEnd.Visible = 0;
            app.VarEditField.Visible = 0;

            % tell code to wait for this script to run
            uiwait(app.UIFigure)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            if strcmp(value, 'Noise Floor')
                app.TextBegin.Text = 'thresh = mean(noise floor)  + ';
                app.TextEnd.Text = '* std(noise floor)';
                app.VarEditField.Value = 3;
            elseif strcmp(value, 'Criterion')
                app.TextBegin.Text = 'thresh = criterion of ';
                app.TextEnd.Text = ' dB SPL';
                app.VarEditField.Value = 0;
            end

            if strcmp(value, ' ')
                app.TextBegin.Visible = 0;
                app.TextEnd.Visible = 0;
                app.VarEditField.Visible = 0;
            else
                app.TextBegin.Visible = 1;
                app.TextEnd.Visible = 1;
                app.VarEditField.Visible = 1;
            end

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
            app.type = app.DropDown.Value;
            app.val = app.VarEditField.Value;

            uiresume(app.UIFigure)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            uiresume(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhatThresholdingStratagydoyouwanttouseLabel = uilabel(app.UIFigure);
            app.WhatThresholdingStratagydoyouwanttouseLabel.Text = 'What Thresholding Stratagy do you want to use';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {' ', 'Noise Floor', 'Criterion'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = ' ';
            app.VarEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarEditField.Value = 3;
            app.TextEnd = uilabel(app.UIFigure);
            app.TextEnd.Text = '* std(noise floor)';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TextBegin = uilabel(app.UIFigure);
            app.TextBegin.Text = 'Thresh = mean(noise floor)  + ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreshTypeDP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end