classdef ThresholdSummary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Group5CheckBox         matlab.ui.control.CheckBox
        Group9CheckBox         matlab.ui.control.CheckBox
        Group8CheckBox         matlab.ui.control.CheckBox
        Group7CheckBox         matlab.ui.control.CheckBox
        Group6CheckBox         matlab.ui.control.CheckBox
        saveButton_Excel       matlab.ui.control.Button
        ErrorBarType           matlab.ui.control.DropDown
        GroupingButton         matlab.ui.control.StateButton
        Group4CheckBox         matlab.ui.control.CheckBox
        Group3CheckBox         matlab.ui.control.CheckBox
        Group2CheckBox         matlab.ui.control.CheckBox
        Group1CheckBox         matlab.ui.control.CheckBox
        GroupingLabel          matlab.ui.control.Label
        assignGroupsButton     matlab.ui.control.Button
        saveButton_Image       matlab.ui.control.Button
        ShowLegendCheckBox     matlab.ui.control.CheckBox
        ThresholdSummaryLabel  matlab.ui.control.Label
        holdCTRLtohighlightmultipleLabel  matlab.ui.control.Label
        ChooseABRstoprocessandplotLabel  matlab.ui.control.Label
        MouseListBox           matlab.ui.control.ListBox
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        thresh = struct; 
        data = []
        list = {};
        fn = [];
        path = [];
        info = struct;
        freq = [];
        groups = [];
        group1 = [];
        group2 = [];
        group3 = [];
        group4 = [];

    end
    
    methods (Access = public)

        function startfunc1(app, list, thresh, path, fn, info)

            % load variables
            app.thresh = thresh;
            app.fn = fn;
            app.path = path;
            app.info = info;
            
            % enable multiselect
            app.MouseListBox.Multiselect = 'on';

            % propogate ListBox.
            for i = 1:length(list)
                app.list{i} = ['----      : ', list{i}];
            end
            app.MouseListBox.Items = app.list;

            % find all the unique frequencies used
            freq = [];
            for i = 1:length(thresh)
                freq = [freq, thresh(i).freq];
            end
            freq = unique(freq);
            app.freq = freq;

            % set up groups
            app.groups = nan(size(list));

            app.group1 = nan(size(freq));
            app.group2 = nan(size(freq));
            app.group3 = nan(size(freq));
            app.group4 = nan(size(freq));

            % reorganize data;
            app.data = nan([length(thresh), length(freq)]);
            for i = 1:size(app.data,1)
                for j = 1:size(app.data,2)
                    if ismember(freq(j), thresh(i).freq)
                        idx = find(thresh(i).freq == freq(j));
                        app.data(i,j) = thresh(i).thresh(idx);
                        app.data(app.data == -inf) = nan; app.data(app.data == inf) = nan;
                    else
                        app.data(i,j) = nan;
                    end
                end
            end

            replot(app)

        end
    end

    methods (Access = private)

        function replot(app)

            % set up markers and colors
            mkrs = ['o', 'p', '+', 'x', 's', 'd', 'v', '^', '<', '>'];
            col = colororder;
            colg = [1,0,0;
                    0,1,0;
                    0,0,1;
                    1,0,1;
                    1,1,0;
                    0,1,1;
                    0,0,0;
                    0.5,0.5,0.5;
                    0.5,1,0];
            count = 1;
            lgdtxt = {};

            % plot setup
            cla(app.UIAxes)
            hold(app.UIAxes, 'on' )

            % see if grouping is selected
            if app.GroupingButton.Value && sum(app.groups, 'omitnan')>0
                % loop through 9 groups
                for i = 1:9
                    % turn group on/off
                    if ismember(i, app.groups)
                        app.(['Group', num2str(i), 'CheckBox']).Value = 1;
                    else
                        app.(['Group', num2str(i), 'CheckBox']).Value = 0;
                    end

                    % see if group checkbox is on to display
                    if app.(['Group', num2str(i), 'CheckBox']).Value

                        % find index of mice in group
                        idx = find(app.groups == i);

                        % gather data
                        toPlotGroup = app.data(idx,:);

                        % find group mean and omit nan
                        toPlotAvg = mean(toPlotGroup, 1, 'omitnan');

                        % plot lines
                        ax(count) = plot(app.UIAxes, app.freq, toPlotAvg, 'linewidth', 2, 'Color', colg(i,:));

                        % plot errorbars
                        if contains(app.ErrorBarType.Value, 'STD')
                            er = std(toPlotGroup, [], 1, 'omitnan');
                        elseif contains(app.ErrorBarType.Value, 'STE')
                            er = std(toPlotGroup, [], 1, 'omitnan') ./ sqrt(sum(~isnan(toPlotGroup)));                        
                        end
                        errorbar(app.UIAxes, app.freq, toPlotAvg, er, 'linewidth', 0.5, 'Color', colg(i,:));

                        % append legend text
                        lgdtxt = [lgdtxt, ['Group ', num2str(i), ' (n = ', num2str(length(idx)), ')']];
                        count = count+1;
                    end
                end
            end

            % catch to make sure something is selected
            if ~isempty(app.MouseListBox.Value) 

                % find selected items in ListBox
                for i = 1:length(app.MouseListBox.Value)
                    ix = find(contains(app.MouseListBox.Items,app.MouseListBox.Value{i}));
                    % catch if multiiple lines exist
                    if length(ix) > 1
                        ix = ix(1);
                    end
                    selected(i) = ix;
                end
                selected = sort(selected);

                % gather all data in toPlot
                toPlot = nan(size(app.data));
                toPlot(selected,:) = app.data(selected,:);

                % loop through the selected animals and plot
                for i = 1:size(toPlot,1)

                    % make sure there is data to plot
                    if sum(~isnan(toPlot(i,:))) > 0

                        % catch to start repeating colors/markers if num
                        % selected > length(mkrs), which is 7, and defind color
                        m = i;
                        while m > length(mkrs)
                            m = m - length(mkrs);
                        end
                        if app.GroupingButton.Value
                            c = (colg(app.groups(i),:)+1)/2;
                        else
                            c = col(m,:);
                        end

                        % plot lines
                        ax(count) = plot(app.UIAxes, app.freq, toPlot(i,:), 'linewidth', 1, 'Color', c);

                        %plot markers, if thresh doesn't exist make symbol '^'
                        for j = 1:size(toPlot,2)
                            if isnan(toPlot(i,j))
                                plot(app.UIAxes, app.freq(j), 100, 'marker', '^', 'linewidth', 1, 'color', c, 'linestyle', 'none')
                            else
                                plot(app.UIAxes, app.freq(j), toPlot(i,j), 'marker', mkrs(m), 'linewidth', 1, 'color', c, 'linestyle', 'none')
                            end
                        end

                        % append legend text
                        lgdtxt = [lgdtxt, strrep(app.MouseListBox.Items{i}(12:end), '_', '-')];
                        count = count+1;
                    end
                end
            end

            ylim(app.UIAxes, [-5 105])
            ylabel(app.UIAxes, 'Threshold [dB]')

            % create octave linspace
            for i = -2:6
                x(i+3) = 2^i * 1000;
            end

            % setup axes
            set(app.UIAxes, 'XScale', 'log')
            xlim(app.UIAxes, [round(min(app.freq)/2) round(max(app.freq)*2)])
            xticks(app.UIAxes, x);
            xlabel(app.UIAxes, 'Frequency [Hz]')

            % setup legend
            if ~isempty(lgdtxt)
                legend(app.UIAxes, ax, lgdtxt)
                if app.ShowLegendCheckBox.Value
                    app.UIAxes.Legend.Visible = 'on';
                else
                    app.UIAxes.Legend.Visible = 'off';
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MouseListBox
        function MouseListBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: GroupingButton
        function GroupingButtonValueChanged(app, event)
            value = app.GroupingButton.Value;
            
            %change state based on grouping on or off
            if value
                % dislay warning if no groups have been found
                if all(isnan(app.groups))
                    app.GroupingButton.Value = 0;
                    warndlg('Groups not assigned')
                    return
                end

                state = 'ON';

                % unselect everything in listbox
                app.MouseListBox.Value = {};
                
            else
                state = 'OFF';
                app.MouseListBox.Value = app.MouseListBox.Items(1);
            end

            % change text and make this (in)visible
            app.GroupingButton.Text = state;
            app.Group1CheckBox.Visible = state;
            app.Group2CheckBox.Visible = state;
            app.Group3CheckBox.Visible = state;
            app.Group4CheckBox.Visible = state;
            app.Group5CheckBox.Visible = state;
            app.Group6CheckBox.Visible = state;
            app.Group7CheckBox.Visible = state;
            app.Group8CheckBox.Visible = state;
            app.Group9CheckBox.Visible = state;
            app.ErrorBarType.Visible = state;

            replot(app)

        end

        % Button pushed function: assignGroupsButton
        function assignGroupsButtonPushed(app, event)
            
            disp('')
            napp2 = MakeGroups;
            startfunc2(napp2, app.MouseListBox.Items, app.groups)
            waitfor(napp2,'waitvalue')
            if isvalid(napp2)
                app.groups = napp2.groups;
                app.MouseListBox.Items = napp2.list;
                close(napp2.UIFigure)

                % check that groups have values and make all the grouping options (in)visible
                if all(isnan(app.groups))
                    app.GroupingButton.Value = false;
                    app.MouseListBox.Value = app.MouseListBox.Items(1);
                    state = 'OFF';
                else
                    app.GroupingButton.Value = true;
                    app.MouseListBox.Value = {};
                    state = 'ON';
                end

                app.GroupingButton.Enable = state;
                app.GroupingButton.Text = state;
                app.Group1CheckBox.Visible = state;
                app.Group2CheckBox.Visible = state;
                app.Group3CheckBox.Visible = state;
                app.Group4CheckBox.Visible = state;
                app.Group5CheckBox.Visible = state;
                app.Group6CheckBox.Visible = state;
                app.Group7CheckBox.Visible = state;
                app.Group8CheckBox.Visible = state;
                app.Group9CheckBox.Visible = state;
                app.ErrorBarType.Visible = state;

                % turn on all groups that have stuff in them
                for i = 1:4
                    if ismember(i, app.groups)
                        app.(['Group', num2str(i), 'CheckBox']).Value = 1;
                    end
                end

                replot(app)
            end

        end

        % Value changed function: Group1CheckBox
        function Group1CheckBoxValueChanged(app, event)
           
            replot(app)
            
        end

        % Value changed function: Group2CheckBox
        function Group2CheckBoxValueChanged(app, event)
           
            replot(app)
            
        end

        % Value changed function: Group3CheckBox
        function Group3CheckBoxValueChanged(app, event)
           
            replot(app)
            
        end

        % Value changed function: Group4CheckBox
        function Group4CheckBoxValueChanged(app, event)
           
            replot(app)
            
        end

        % Value changed function: Group5CheckBox
        function Group5CheckBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: Group6CheckBox
        function Group6CheckBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: Group7CheckBox
        function Group7CheckBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: Group8CheckBox
        function Group8CheckBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: Group9CheckBox
        function Group9CheckBoxValueChanged(app, event)
            
            replot(app)
            
        end

        % Value changed function: ErrorBarType
        function ErrorBarTypeValueChanged(app, event)
           
            replot(app)
            
        end

        % Button pushed function: saveButton_Image
        function saveButton_ImagePushed(app, event)
            
            % define types of files the figure can be saved as
            type = {'*.png'; '*.jpg'; '*.tif'; '*.svg'; '*.pdf'; '*.fig'};

            % select folder and file name to save to
            [file,path,idx] = uiputfile(type, 'File Selection', fullfile(app.path, app.fn));

            % catch to make sure the user choose a file
            if sum(file) == 0
                return
            end

            % make saveName
            saveName = fullfile(path, file);

            saveImage(saveName, app.UIAxes, type{idx})

        end

        % Button pushed function: saveButton_Excel
        function saveButton_ExcelPushed(app, event)
           
                % select folder and file name to save to
                [file,path] = uiputfile(fullfile(app.path, [app.fn, '_ThresholdSummary', '.xlsx']));

                % catch to make sure the user choose a file
                if sum(file) == 0
                    return
                end

                % make saveName
                saveName = fullfile(path, file);

                % if file exists and user wanted to replace it, delete original
                if isfile(saveName)
                    delete(saveName);
                end

                % open waitbar window
                w = waitbar(0,'Please Wait, Saving Excel');

                saveExcel_Thresholds(saveName, app.info, app.thresh, app.groups)
                
                % close waitbar window
                close(w)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MouseListBox = uilistbox(app.UIFigure);
            app.MouseListBox.ValueChangedFcn = createCallbackFcn(app, @MouseListBoxValueChanged, true);
            app.ChooseABRstoprocessandplotLabel = uilabel(app.UIFigure);
            app.ChooseABRstoprocessandplotLabel.Text = 'Choose ABRs to process and plot';
            app.holdCTRLtohighlightmultipleLabel = uilabel(app.UIFigure);
            app.holdCTRLtohighlightmultipleLabel.Text = '(hold CTRL to highlight multiple)';
            app.ThresholdSummaryLabel = uilabel(app.UIFigure);
            app.ThresholdSummaryLabel.Text = 'Threshold Summary';
            app.ShowLegendCheckBox = uicheckbox(app.UIFigure);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.saveButton_Image = uibutton(app.UIFigure, 'push');
            app.saveButton_Image.ButtonPushedFcn = createCallbackFcn(app, @saveButton_ImagePushed, true);
            app.saveButton_Image.Text = 'Save Image';
            app.assignGroupsButton = uibutton(app.UIFigure, 'push');
            app.assignGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @assignGroupsButtonPushed, true);
            app.assignGroupsButton.Text = 'Assign Groups';
            app.GroupingLabel = uilabel(app.UIFigure);
            app.GroupingLabel.Text = 'Grouping';
            app.Group1CheckBox = uicheckbox(app.UIFigure);
            app.Group1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group1CheckBoxValueChanged, true);
            app.Group1CheckBox.Visible = 'off';
            app.Group1CheckBox.Text = 'Group 1';
            app.Group2CheckBox = uicheckbox(app.UIFigure);
            app.Group2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group2CheckBoxValueChanged, true);
            app.Group2CheckBox.Visible = 'off';
            app.Group2CheckBox.Text = 'Group 2';
            app.Group3CheckBox = uicheckbox(app.UIFigure);
            app.Group3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group3CheckBoxValueChanged, true);
            app.Group3CheckBox.Visible = 'off';
            app.Group3CheckBox.Text = 'Group 3';
            app.Group4CheckBox = uicheckbox(app.UIFigure);
            app.Group4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group4CheckBoxValueChanged, true);
            app.Group4CheckBox.Visible = 'off';
            app.Group4CheckBox.Text = 'Group 4';
            app.GroupingButton = uibutton(app.UIFigure, 'state');
            app.GroupingButton.ValueChangedFcn = createCallbackFcn(app, @GroupingButtonValueChanged, true);
            app.GroupingButton.Enable = 'off';
            app.GroupingButton.Text = 'OFF';
            app.ErrorBarType = uidropdown(app.UIFigure);
            app.ErrorBarType.Items = {'STD', 'STE'};
            app.ErrorBarType.ValueChangedFcn = createCallbackFcn(app, @ErrorBarTypeValueChanged, true);
            app.ErrorBarType.Visible = 'off';
            app.ErrorBarType.Value = 'STD';
            app.saveButton_Excel = uibutton(app.UIFigure, 'push');
            app.saveButton_Excel.ButtonPushedFcn = createCallbackFcn(app, @saveButton_ExcelPushed, true);
            app.saveButton_Excel.Text = 'Save Thesh Excel';
            app.Group6CheckBox = uicheckbox(app.UIFigure);
            app.Group6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group6CheckBoxValueChanged, true);
            app.Group6CheckBox.Visible = 'off';
            app.Group6CheckBox.Text = 'Group 6';
            app.Group7CheckBox = uicheckbox(app.UIFigure);
            app.Group7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group7CheckBoxValueChanged, true);
            app.Group7CheckBox.Visible = 'off';
            app.Group7CheckBox.Text = 'Group 7';
            app.Group8CheckBox = uicheckbox(app.UIFigure);
            app.Group8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group8CheckBoxValueChanged, true);
            app.Group8CheckBox.Visible = 'off';
            app.Group8CheckBox.Text = 'Group 8';
            app.Group9CheckBox = uicheckbox(app.UIFigure);
            app.Group9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group9CheckBoxValueChanged, true);
            app.Group9CheckBox.Visible = 'off';
            app.Group9CheckBox.Text = 'Group 9';
            app.Group5CheckBox = uicheckbox(app.UIFigure);
            app.Group5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Group5CheckBoxValueChanged, true);
            app.Group5CheckBox.Visible = 'off';
            app.Group5CheckBox.Text = 'Group 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdSummary

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end