classdef plotABRs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InvertWaveformCheckBox       matlab.ui.control.CheckBox
        SystemGainEditField          matlab.ui.control.NumericEditField
        SystemGainEditFieldLabel     matlab.ui.control.Label
        NormHeightCheckBox           matlab.ui.control.CheckBox
        ScaleBarPanel                matlab.ui.container.Panel
        ScaleBarDropDown             matlab.ui.control.DropDown
        ShowScaleBarCheckBox         matlab.ui.control.CheckBox
        return_button                matlab.ui.control.Button
        saveButton_peaks             matlab.ui.control.Button
        AutoThresholdButton          matlab.ui.control.Button
        saveButton_Image             matlab.ui.control.Button
        FilterDropDown               matlab.ui.control.DropDown
        AddButterworthFilterLabel_2  matlab.ui.control.Label
        LowFilterEditField           matlab.ui.control.NumericEditField
        LowEditFieldLabel            matlab.ui.control.Label
        HighFilterEditField          matlab.ui.control.NumericEditField
        HighEditFieldLabel           matlab.ui.control.Label
        saveButton_xlsx              matlab.ui.control.Button
        saveButton_mat               matlab.ui.control.Button
        ThreshSummaryButton          matlab.ui.control.Button
        ThresholdLabel               matlab.ui.control.Label
        MouseNameLabel               matlab.ui.control.Label
        MouseListBox                 matlab.ui.control.ListBox
        FrequenciesLabel             matlab.ui.control.Label
        FreqListBox                  matlab.ui.control.ListBox
        InformationTextArea          matlab.ui.control.TextArea
        ProjectFNLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % set up variables that will be in app handle
        fn = []; % variable for storing filename
        path = []; %variable for storing original file path
        list = {}; % cell for listing all the groups
        details = struct; % setup details struct
        thresh = struct; % setup threshold struct
        idx_group = 1; % index of what group is currently selected
        idx_freq = 1; % index of what frequency is currently selected
        scale = 1; % scaling value to increase or decrease waveform size
        norm = 0; % normalize plot yes/no
        sep = 1e-6; % seperation of lines on plot (100 nV default)
        autothresh = 0; % was the autothreshold run
        pkfind = 0; % see peakfinder is activated
        ndfind = 0; % see node in peakfinder is activated 
        ndslt = 0; % to look at peaks (0) or nodes (1)
        master_locs = []; % this is a control feature for helping find peaks
        master_locs_nodes = []; % this is a control feature for helping find nodes
        idx_pk = nan; % index of what peak is currently selected, nan if nothing selected
        ctrl = 0; % see if control key is pressed
        shft = 0; % see if shift key is pressed
    end
    
    methods (Access = public)
        
        function startfunc(app, path, fn, data, thresh)

            % open waitbar window
            w = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading Files');

            % write the experiment name at top
            app.ProjectFNLabel.Text = fn;
            app.fn = fn;
            app.path = path;

            % add peak finder structure if not already there
            for i = 1:length(data)
                for j = 1:length(data(i).frequencies)
                    if ~isfield(data(i).(['Freq', num2str(j)]), 'peaks')
                        % add structure that is number of levels x 2 for amplitude(1) and time(2) x 5 peaks
                        data(i).(['Freq', num2str(j)]).peaks.pks = nan(length(data(i).(['Freq', num2str(j)]).levels),2,5);
                        data(i).(['Freq', num2str(j)]).peaks.nodes = nan(length(data(i).(['Freq', num2str(j)]).levels),2,5);
                    end
                end
            end

            % load up variables
            app.master_locs = nan(length(data),length(data(i).frequencies),5); % groups x freqs x 5 (number of peaks)
            app.master_locs_nodes = nan(length(data),length(data(i).frequencies),5); % groups x freqs x 5 (number of peaks)
            app.details = data;

            % setup thresholds structure if not exist
            if ~exist('thresh', 'var')
                for i = 1:length(data)
                    % fill in frequency info
                    app.thresh(i).freq = app.details(i).frequencies;
                    % loop through frequencies
                    for j = 1:length(app.thresh(i).freq)
                        % set default threshold to -inf, may change later if criteria met
                        app.thresh(i).temp(j) = -inf;
                        app.thresh(i).thresh(j) = -inf;
                        app.thresh(i).user(j) = 0;
                    end
                end
            else
                app.thresh = thresh;
            end

            % make list to propogate group listbox
            for i = 1:length(data)
                if ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref1) && ~isempty(app.details(i).ref2)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref1, ' : ', app.details(i).ref2];
                elseif ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref1)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref1];
                elseif ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref2)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref2];
                else
                    app.list{i} = app.details(i).ID;
                end
                % catch if there is a double entry and add a number to it
                val = length(find(contains(app.list,app.list{i})));
                if val > 1
                    app.list{i} = [app.list{i}, ' n', num2str(val)];
                end
            end

            % add default gain to plot
            if isfield(app.details(1).Freq1, 'gain')
                app.SystemGainEditField.Value = app.details(1).Freq1.gain;
            else
                app.SystemGainEditField.Value = 1;
            end


            % propogate ListBox.
            app.MouseListBox.Items = app.list; 

            % run replot function
            replot(app);

            % close waitbar window
            close(w)
        end
        
    end
    
    methods (Access = private)
        
        function replot(app)

            % catch for bad filter values
            if app.LowFilterEditField.Visible
                low = app.LowFilterEditField.Value;
                if low == 0 || low == inf
                    waitfor(msgbox('Filter Value cannot be 0 or inf'))
                    return
                end
            end
            if app.HighFilterEditField.Visible
                high = app.HighFilterEditField.Value;
                if high == 0 || high == inf
                    waitfor(msgbox('Filter Value cannot be 0 or inf'))
                    return
                end
            end

            % if normalize is enabled, recalculate scale
            if app.norm
                app.scale = normalizeScale(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform * app.SystemGainEditField.Value, app.sep);
            end

            % catch to make sure peaks are available to look at otherwise turn off
            if any(any(any(isnan(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks))))
                app.pkfind = 0;
                app.saveButton_peaks.Visible = 0;
            else
                app.pkfind = 1;
                app.saveButton_peaks.Visible = 1;
            end

            % propogate FreqBox.
            app.FreqListBox.Items = app.details(app.idx_group).freqlist;

            % rewrite threshold text
            app.ThresholdLabel.Text = ['Threshold = ', num2str(app.thresh(app.idx_group).thresh(app.idx_freq)), ' dB']; 

            % extract data for plotting and add system gain if necessary
            toPlotAll = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform * app.SystemGainEditField.Value;
            levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;
            time = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time;
            sr = round(1000000/app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).samp_dur);
            pkcol = ['r', 'y', 'g', 'c', 'b'];
            
            % write detail text on GUI
            txt = rewriteTxt(app.details,app.idx_group,app.idx_freq);
            app.InformationTextArea.Value = txt;

            % clear figure and plot
            cla(app.UIAxes)            
            hold(app.UIAxes, 'on' )
            
            for i = 1:size(toPlotAll,1)

                % extract single waveform and add user defined filter and gain
                toPlot = squeeze(toPlotAll(i,:));
                toPlot = runfilter(app, toPlot, sr); 

                % define line color based on threshold
                if app.thresh(app.idx_group).temp(app.idx_freq) == levels(i)
                    col1 = 'r';
                else
                    col1 = 'k';
                end

                % define line style based on threshold
                if app.thresh(app.idx_group).thresh(app.idx_freq) <= levels(i)
                    style = '-';
                else
                    style = '--';
                end

                plot(app.UIAxes, time, (toPlot*app.scale) + (app.sep*(i-1)), 'linewidth', 2, 'color', col1, 'LineStyle',style)

                % display peaks if turned on and greater than threshold
                if app.pkfind
                    if app.thresh(app.idx_group).thresh(app.idx_freq) <= levels(i)

                        % loop through all 5 peaks
                        for k = 1:5
                            
                            pk = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,1,k);
                            loc = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,2,k);

                            % catch for backwards compatibility, make sure proper gain in applied
                            [~, loc_samp] = min(abs(time - loc));
                            if toPlot(loc_samp) ~= pk
                                pk = toPlot(loc_samp);
                                app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,1,k) = pk;
                            end

                            % plot all peaks if not selected level and peak, will plot that later so its on top
                            if app.thresh(app.idx_group).temp(app.idx_freq) == levels(i) 
                                if app.idx_pk ~= k
                                    plot(app.UIAxes, loc, pk*app.scale + (app.sep*(i-1)), 'marker', 'o', 'LineStyle','none', 'markerfacecolor', pkcol(k), 'MarkerEdgeColor', 'k')
                                end
                            else
                                plot(app.UIAxes, loc, pk*app.scale + (app.sep*(i-1)), 'marker', 'o', 'LineStyle','none', 'markerfacecolor', pkcol(k), 'MarkerEdgeColor', 'k')
                            end
                        end

                        if app.ndfind
                            % loop through all 5 peaks
                            for k = 1:5

                                pk = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,1,k);
                                loc = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,2,k);

                                % catch for backwards compatibility, make sure proper gain in applied
                                [~, loc_samp] = min(abs(time - loc));
                                if toPlot(loc_samp) ~= pk
                                    pk = toPlot(loc_samp);
                                    app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,1,k) = pk;
                                end

                                % plot all peaks if not selected level and peak, will plot that later so its on top
                                if app.thresh(app.idx_group).temp(app.idx_freq) == levels(i)
                                    if app.idx_pk ~= k
                                        plot(app.UIAxes, loc, pk*app.scale + (app.sep*(i-1)), 'marker', 's', 'LineStyle','none', 'markerfacecolor', pkcol(k), 'MarkerEdgeColor', 'k')
                                    end
                                else
                                    plot(app.UIAxes, loc, pk*app.scale + (app.sep*(i-1)), 'marker', 's', 'LineStyle','none', 'markerfacecolor', pkcol(k), 'MarkerEdgeColor', 'k')
                                end
                            end

                        end

                        % plot selected peak white
                        if ~isnan(app.idx_pk) && app.thresh(app.idx_group).temp(app.idx_freq) == levels(i)

                            pk1 = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,1,app.idx_pk);
                            loc1 = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,2,app.idx_pk);
                            pk2 = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,1,app.idx_pk);
                            loc2 = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,2,app.idx_pk);

                            if app.ndslt
                                plot(app.UIAxes, loc1, pk1*app.scale + (app.sep*(i-1)), 'marker', 'o', 'LineStyle','none', 'markerfacecolor', pkcol(app.idx_pk), 'MarkerEdgeColor', 'k')
                                plot(app.UIAxes, loc2, pk2*app.scale + (app.sep*(i-1)), 'marker', 's', 'LineStyle','none', 'markerfacecolor', 'w', 'MarkerEdgeColor', 'k')
                            else
                                plot(app.UIAxes, loc1, pk1*app.scale + (app.sep*(i-1)), 'marker', 'o', 'LineStyle','none', 'markerfacecolor', 'w', 'MarkerEdgeColor', 'k')
                                plot(app.UIAxes, loc2, pk2*app.scale + (app.sep*(i-1)), 'marker', 's', 'LineStyle','none', 'markerfacecolor', pkcol(app.idx_pk), 'MarkerEdgeColor', 'k')
                            end
                        end

                    end                    
                end

            end
    
            % axes details
            yticks(app.UIAxes, 0:app.sep:app.sep * (i-1));
            yticklabels(app.UIAxes, levels)
            ylabel(app.UIAxes, 'Level [dB]')
            xlabel(app.UIAxes, 'Time [ms]')
            ylim(app.UIAxes, [-1 size(toPlotAll,1)]*app.sep)
            xlim(app.UIAxes, [0 10])
            set(app.UIAxes,'TickDir','out')

            % based on input unit, make text for scale bar
            switch app.ScaleBarDropDown.Value
                case '50 mV'
                    txt = '50 mV';
                case '5 mV'
                    txt = '5 mV';
                case '500 uV'
                    txt = '500 uV';
                case '50 uV'
                    txt = '50 uV';
                case '5 uV'
                    txt = '5 uV';
                case '500 nV'
                    txt = '500 nV';
                case '50 nV'
                    txt = '50 nV';
                case '5 nV'
                    txt = '5 nV';
            end

            %draw scalebar
            if app.ShowScaleBarCheckBox.Value
%                 line(app.UIAxes,[9,9],[0,0.5*app.scale]*app.sep+(app.sep*size(toPlot,1)/2+app.sep*1/4),'linewidth',2, 'color', 'k');
%                 text(app.UIAxes,9.1,0.2*app.scale*app.sep+(app.sep*size(toPlot,1)/2+app.sep*1/4),txt);
                line(app.UIAxes,[9,9],[0,0.5*app.scale]*app.sep+(app.sep*size(toPlot,1)/2),'linewidth',2, 'color', 'k');
                text(app.UIAxes,9.1,0.2*app.scale*app.sep+(app.sep*size(toPlot,1)/2),txt);
            end

        end
        
        function app = autoABRThresh(app, w)
            % loop through all groups and frequencies and construct threshold struct
            % w is waitbar to be updated

            % calculate total number of trials for waitbar
            tot = 0;
            for i = 1:length(app.details)
                tot = tot + length(app.details(i).frequencies);
            end

            % loop through groups
            count = 1;
            for i = 1:length(app.details)
                                
                % loop through frequencies
                for j = 1:length(app.thresh(i).freq)
                    
                    % extract waveform and levels
                    levels = app.details(i).(['Freq', num2str(j)]).levels;
                    waveform = app.details(i).(['Freq', num2str(j)]).waveform * app.SystemGainEditField.Value;

                    % add filter if filter was added
                    waveform = runfilter(app, waveform); 

                    % only run autothreshold if there are atleast 3 levels
                    if length(levels) > 3 && ~app.thresh(i).user(j)

                        % run through algorithm to find threshold
                        th = estimate_abr_threshold_SM(waveform', levels');

                        % make sure th isn't empty and update thresh struct
                        if ~isempty(th)
                            app.thresh(i).thresh(j) = th;
                        else
                            app.thresh(i).thresh(j) = inf;
                        end
                    else
                        app.thresh(i).thresh(j) = inf;
                    end
                    w.Value = count/(tot);
                    count = count + 1;
                end
                
                %set up thresh.temp field which is needed for app functionality
                app.thresh(i).temp = app.thresh(i).thresh;
            end
            
        end
        
        function Y = runfilter(app, Y, sr)
            % function to check and see if user has set a filter and apply filter
            
            % check if filter has been applied
            if ~strcmp(app.FilterDropDown.Value, 'None')

                % add high and low cuttoffs of filter depending on type
                % numbers can only be positive whole numers
                low = nan; high = nan;
                if app.LowFilterEditField.Visible
                    low = app.LowFilterEditField.Value;
                end
                if app.HighFilterEditField.Visible
                    high = app.HighFilterEditField.Value;
                end

                % apply filter
                for i = 1:size(Y,1)
                    Y(i,:) = FIRfilter(Y(i,:), 28, sr, [low, high]);
                end
            end

        end
        
        
        function peakfinder_master(app)
            % function to find all the peaks of highest level

            % some default settings to help narrow the search for peaks
            firstpk = 1.5; % set the first peak and set up the rest, 1 ms difference between
            for k = 1:5
                constraints(k) = firstpk + (k-1)*1.2; % peaks should be close to the following times (in ms)
            end
%             constraints = [1.5, 2.5, 3.5, 4.5, 5.5]; % peaks should be close to the following times (in ms)
            std = 0.5; % how far off the peaks can be to the constrain above, plus or minus


            % extract waveform of highest level to find initial peaks
            y = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform(end,:) * app.SystemGainEditField.Value;

            % find all peaks
            [pks,locs,~,ps] = findpeaks(y);
            locs = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time(locs);

            for k = 1:5
                % for each peak, 1-5, find the biggest peak closest to constrain
                loc = locs(locs > constraints(k)-std & locs < constraints(k)+std);
                p = ps(locs > constraints(k)-std & locs < constraints(k)+std);

                % catch if there is more than peak that fits the criteria
                if length(loc) > 1
                    [~, idx] = max(p);
                    loc = loc(idx);
                end

                % catch if there is no peak that fits the criteria
                if isempty(loc)
                    [~, idx] = min(abs(constraints(k) - locs));
                    loc = locs(idx);
                end

                app.master_locs(app.idx_group,app.idx_freq,k) = loc;
                peakfinder_slave(app, length(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels), k);
            end

        end
        
        function peakfinder_slave(app, level, peak)
            % function to find all the peaks of each level based on those found in the master file

            % set up buffer for finding peaks, how much before is the lower level peak allowed to be?
            negbuffer = -0.1;

            for i = flip(1:level)

                % extract waveform of level to find initial peaks
                y = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform(i,:) * app.SystemGainEditField.Value;

                % find peaks
                [pks,locs] = findpeaks(y);
                locs = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time(locs);

                % find the closest peak the master_locs
                a = locs - app.master_locs(app.idx_group,app.idx_freq,peak);
                a(a < negbuffer) = nan;
                [~, idx] = min(a);
                app.master_locs(app.idx_group,app.idx_freq,peak) = locs(idx);

                % update structure, levels x 2 for amplitude(1) and time(2) x 5 peaks
                app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,1,peak) = pks(idx);
                app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,2,peak) = locs(idx);
            end

        end
        
        function nodefinder_master(app)
            % function to find all the nodes of highest level

            % get locations of peaks at highest level
            master_peaks = squeeze(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(end,2,:));

            % extract waveform of highest level to find initial peaks
            y = -1*app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform(end,:) * app.SystemGainEditField.Value;

            % find all peaks
            [pks,locs,~,ps] = findpeaks(y);
            locs = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time(locs);

            for k = 1:5
                % for each peak, 1-5, find the biggest peak closest to constrain
                loc = locs(locs > master_peaks(k) & locs < master_peaks(k)+1);
                p = ps(locs > master_peaks(k) & locs < master_peaks(k)+1);


                % catch if there is more than peak that fits the criteria
                if length(loc) > 1
                    [~, idx] = max(p);
                    loc = loc(idx);
                end

                % catch to make sure loc has something
                if isempty(loc)
                    loc = master_peaks(k);
                end

                app.master_locs_nodes(app.idx_group,app.idx_freq,k) = loc;
                nodefinder_slave(app, length(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels), k);
            end
            
        end
        
        function nodefinder_slave(app, level, peak)
            % function to find all the nodes of each level based on those found in the master file

            % set up buffer for finding peaks, how much before is the lower level peak allowed to be?
            negbuffer = -0.1;

            for i = flip(1:level)

                % extract waveform of level to find initial peaks
                y = -1*app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform(i,:) * app.SystemGainEditField.Value;

                % find peaks
                [pks,locs] = findpeaks(y);
                locs = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time(locs);

                % find the closest node the master_locs_nodes
                a = locs - app.master_locs_nodes(app.idx_group,app.idx_freq,peak);
                a(a < negbuffer) = nan;
                [~, idx] = min(a);

                % catch, make sure node comes after peak
                if app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,2,peak) > locs(idx)
                    a = locs - app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(i,2,peak);
                    a(a < negbuffer) = nan;
                    [~, idx] = min(a);
                end

                app.master_locs_nodes(app.idx_group,app.idx_freq,peak) = locs(idx);

                % update structure, levels x 2 for amplitude(1) and time(2) x 5 peaks
                app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,1,peak) = -1*pks(idx);
                app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(i,2,peak) = locs(idx);
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FreqListBox
        function FreqListBoxValueChanged(app, event)
            
            value = app.FreqListBox.Value;
%             app.scale = 1; app.norm = 0; % reset normalization
            app.idx_freq = find(strcmp(app.FreqListBox.Items,value));
            app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
            replot(app)

        end

        % Value changed function: MouseListBox
        function MouseListBoxValueChanged(app, event)
           
            value = app.MouseListBox.Value;
%             app.scale = 1; app.norm = 0; % reset normalization
            app.idx_group = find(strcmp(app.MouseListBox.Items,value));
            % catch error if idx_group has multiple enteries
            if length(app.idx_group) > 1
                app.idx_group = app.idx_group(1);
            end
            app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
            replot(app)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            key = event.Key;

            % signal that the ctrl key is pressed
            if strcmp(key,'control') 
                app.ctrl = 1;
            end

            % signal that the shift key is pressed
            if strcmp(key,'shift') 
                app.shft = 1;
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
           
            key = event.Key;

            % scaling
            if strcmp(key,'add') || strcmp(key,'equal') 
                if app.scale <= 1
                    app.scale = app.scale + 0.1; %scale plot up
                else
                    app.scale = app.scale + 0.5; %scale plot up
                end
            elseif strcmp(key,'subtract') || strcmp(key,'hyphen') 
                if app.scale <= 1
                    if app.scale > 0
                        app.scale = app.scale - 0.1; %scale plot down
                    end
                else
                    app.scale = app.scale - 0.5; %scale plot down
                end
            end

            % activate peak finder
            if strcmp(key,'p')
                if app.pkfind
                    app.pkfind = 0;
                    app.saveButton_peaks.Visible = 0;
                else
                    app.pkfind = 1;
                    app.saveButton_peaks.Visible = 1;
                    if any(any(any(isnan(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks))))
                        peakfinder_master(app)
                    end
                end
            end

            % controls for peak finder
            if app.pkfind && abs(app.thresh(app.idx_group).temp(app.idx_freq)) ~= inf

                % find nodes
                if  strcmp(key,'i') 
                    app.ndfind = 1;
                    if any(any(any(isnan(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes))))
                        nodefinder_master(app)
                    end
                end

                % get index of peak
                if strcmp(key,'1')
                    app.idx_pk = 1;
                elseif strcmp(key,'2')
                    app.idx_pk = 2;
                elseif strcmp(key,'3')
                    app.idx_pk = 3;
                elseif strcmp(key,'4')
                    app.idx_pk = 4;
                elseif strcmp(key,'5')
                    app.idx_pk = 5;
                end

                % shift held down so nodes
                if app.shft && (strcmp(key,'1') || strcmp(key,'2') || strcmp(key,'3') || strcmp(key,'4') || strcmp(key,'5'))
                    app.ndslt = 1;
                elseif ~app.shft && (strcmp(key,'1') || strcmp(key,'2') || strcmp(key,'3') || strcmp(key,'4') || strcmp(key,'5'))
                    app.ndslt = 0;
                end

                % move seleted peak left/right
                if ~app.ctrl
                    if (strcmp(key,'rightarrow') || strcmp(key,'leftarrow')) && ~isnan(app.idx_pk)

                        % gather data of selected level and peak
                        level = find(app.thresh(app.idx_group).temp(app.idx_freq) == app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels);
                        if app.ndslt
                            oldloc = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,2,app.idx_pk);
                        else
                            oldloc = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,2,app.idx_pk);
                        end
                        y = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform(level,:);
                        time = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time;

                        if app.shft
                            [~,idx] = min(abs(time - oldloc));
                            if strcmp(key,'rightarrow')
                                if idx < length(time)
                                    if app.ndslt
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,1,app.idx_pk) = y(idx+1);
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,2,app.idx_pk) = time(idx+1);
                                    else
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,1,app.idx_pk) = y(idx+1);
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,2,app.idx_pk) = time(idx+1);
                                    end
                                end
                            elseif strcmp(key,'leftarrow')
                                if idx > 1
                                    if app.ndslt
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,1,app.idx_pk) = y(idx-1);
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,2,app.idx_pk) = time(idx-1);
                                    else
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,1,app.idx_pk) = y(idx-1);
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,2,app.idx_pk) = time(idx-1);
                                    end
                                end
                            end
                        else
                            % find peaks of selected levels for course adjustment
                            if app.ndslt
                                [~,locs] = findpeaks(-y);
                            else
                                [~,locs] = findpeaks(y);
                            end
                            [~,idx] = min(abs(time(locs) - oldloc));
                            if strcmp(key,'rightarrow')
                                if locs(idx) < max(locs)
                                    if app.ndslt
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,1,app.idx_pk) = y(locs(idx+1));
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,2,app.idx_pk) = time(locs(idx+1));
                                    else
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,1,app.idx_pk) = y(locs(idx+1));
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,2,app.idx_pk) = time(locs(idx+1));
                                    end
                                end
                            elseif strcmp(key,'leftarrow')
                                if locs(idx) > min(locs)
                                    if app.ndslt
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,1,app.idx_pk) = y(locs(idx-1));
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,2,app.idx_pk) = time(locs(idx-1));
                                    else
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,1,app.idx_pk) = y(locs(idx-1));
                                        app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,2,app.idx_pk) = time(locs(idx-1));
                                    end
                                end
                            end
                        end
                    end
                end


                % update selected peaks or nodes
                if strcmp(key,'u')
                    level = find(app.thresh(app.idx_group).temp(app.idx_freq) == app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels);
                    if app.ndslt
                        oldloc = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes(level,2,app.idx_pk);
                        app.master_locs_nodes(app.idx_group,app.idx_freq,app.idx_pk) = oldloc;
                        if level>1
                            nodefinder_slave(app, level-1, app.idx_pk)
                        end
                    else
                        oldloc = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks(level,2,app.idx_pk);
                        app.master_locs(app.idx_group,app.idx_freq,app.idx_pk) = oldloc;
                        if level>1
                            peakfinder_slave(app, level-1, app.idx_pk)
                        end
                    end
                end
            end

            % move through frequencies or mice if ctrl is held down
            if app.ctrl

                % advance through mice
                if strcmp(key,'downarrow')
                    if app.idx_group < length(app.MouseListBox.Items)
                        app.idx_group = app.idx_group + 1;
                        app.MouseListBox.Value = app.MouseListBox.Items(app.idx_group);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
%                         app.scale = 1; app.norm = 0; % reset normalization
                    end
                elseif strcmp(key,'uparrow')
                    if app.idx_group > 1
                        app.idx_group = app.idx_group - 1;
                        app.MouseListBox.Value = app.MouseListBox.Items(app.idx_group);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
%                         app.scale = 1; app.norm = 0; % reset normalization
                    end
                end

                % advance through frequencies
                if strcmp(key,'rightarrow')
                    if app.idx_freq < length(app.FreqListBox.Items)
                        app.idx_freq = app.idx_freq + 1;
                        app.FreqListBox.Value = app.FreqListBox.Items(app.idx_freq);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
%                         app.scale = 1; app.norm = 0; % reset normalization
                    end
                elseif strcmp(key,'leftarrow')
                    if app.idx_freq > 1
                        app.idx_freq = app.idx_freq - 1;
                        app.FreqListBox.Value = app.FreqListBox.Items(app.idx_freq);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
%                         app.scale = 1; app.norm = 0; % reset normalization
                    end
                end

            else

                % extract waveform and levels
                levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;

                % move curser up and down levels and set threshold
                if strcmp(key,'uparrow')
                    if app.thresh(app.idx_group).temp(app.idx_freq) == -inf
                        app.thresh(app.idx_group).temp(app.idx_freq) = min(levels);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) < max(levels)
                        idx = find(app.thresh(app.idx_group).temp(app.idx_freq)  == levels);
                        app.thresh(app.idx_group).temp(app.idx_freq) = levels(idx+1);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) == max(levels)
                        app.thresh(app.idx_group).temp(app.idx_freq) = inf;
                    end
                elseif strcmp(key,'downarrow')
                    if app.thresh(app.idx_group).temp(app.idx_freq) == inf
                        app.thresh(app.idx_group).temp(app.idx_freq) = max(levels);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) > min(levels)
                        idx = find(app.thresh(app.idx_group).temp(app.idx_freq)  == levels);
                        app.thresh(app.idx_group).temp(app.idx_freq) = levels(idx-1);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) == min(levels)
                        app.thresh(app.idx_group).temp(app.idx_freq) = -inf;
                    end
                elseif strcmp(key,'return')
                    app.thresh(app.idx_group).user(app.idx_freq) = 1;
                    app.thresh(app.idx_group).thresh(app.idx_freq) = app.thresh(app.idx_group).temp(app.idx_freq);
                end
            end

            % normalize plots for easier viewing
            if strcmp(key,'n')
                app.norm = ~app.norm;
                app.scale = 1; % reset normalization
                app.NormHeightCheckBox.Value = app.norm;
                
                if app.norm
                    app.scale = normalizeScale(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform * app.SystemGainEditField.Value, app.sep);
                end
            end

            % signal that the ctrl key is no longer pressed
            if strcmp(key,'control') 
                app.ctrl = 0;
            end

            % signal that the shift key is no longer pressed
            if strcmp(key,'shift') 
                app.shft = 0;
            end

            replot(app)

        end

        % Button pushed function: ThreshSummaryButton
        function ThreshSummaryButtonPushed(app, event)
            
            % determine if any thresholds have been manually set
            cont = 0;
            for i = 1:length(app.thresh)
                if any(app.thresh(i).user)
                    cont = 1;
                end
            end

            if cont || app.autothresh
                napp1 = ThresholdSummary;
                startfunc1(napp1, app.list, app.thresh, app.path, app.fn, app.details);
                waitfor(napp1)
            else
                % display warning message
                waitfor(warndlg('Run Authothresholds or manually select thresholds'))
            end

        end

        % Value changed function: SystemGainEditField
        function SystemGainEditFieldValueChanged(app, event)
            
            replot(app);
            
        end

        % Value changed function: InvertWaveformCheckBox
        function InvertWaveformCheckBoxValueChanged(app, event)
            
            % invert wav of selected group
            wav = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform;
            app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform = -1 * wav;
            
            % clear peaks of this group
            app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks = nan(size(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks));
            app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes = nan(size(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes));

            replot(app);
            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;

            switch value
                case 'None'
                    app.LowFilterEditField.Visible = 0;
                    app.LowEditFieldLabel.Visible = 0;
                    app.HighFilterEditField.Visible = 0;
                    app.HighEditFieldLabel.Visible = 0;
                case 'LowPass'
                    app.LowFilterEditField.Visible = 0;
                    app.LowEditFieldLabel.Visible = 0;
                    app.HighFilterEditField.Visible = 1;
                    app.HighEditFieldLabel.Visible = 1;
                case 'HighPass'
                    app.LowFilterEditField.Visible = 1;
                    app.LowEditFieldLabel.Visible = 1;
                    app.HighFilterEditField.Visible = 0;
                    app.HighEditFieldLabel.Visible = 0;
                case 'BandPass'
                    app.LowFilterEditField.Visible = 1;
                    app.LowEditFieldLabel.Visible = 1;
                    app.HighFilterEditField.Visible = 1;
                    app.HighEditFieldLabel.Visible = 1;
            end

            replot(app);
            
        end

        % Value changed function: HighFilterEditField
        function HighFilterEditFieldValueChanged(app, event)

            % numbers can only be positive whole numers
            value = app.HighFilterEditField.Value;
            app.HighFilterEditField.Value = abs(round(value));

            replot(app);

        end

        % Value changed function: LowFilterEditField
        function LowFilterEditFieldValueChanged(app, event)

            % numbers can only be positive whole numers
            value = app.LowFilterEditField.Value;
            app.LowFilterEditField.Value = abs(round(value));

            replot(app);

        end

        % Button pushed function: saveButton_xlsx
        function saveButton_xlsxPushed(app, event)

            % add high and low cuttoffs of filter depending on type, nan otherwise
            % numbers can only be positive whole numers
            low = nan; high = nan;
            if app.LowFilterEditField.Visible
                low = app.LowFilterEditField.Value;
            end
            if app.HighFilterEditField.Visible
                high = app.HighFilterEditField.Value;
            end

            saveExcel(app.path, app.fn, app.list, app.details, app.thresh, [low, high])
            
        end

        % Button pushed function: saveButton_peaks
        function saveButton_peaksPushed(app, event)
            
            % catch to make sure that all groups/freqs that have peaks also have nodes
            cont = 1;
            for i = 1:length(app.details)
                for j = 1:length(app.thresh(i).freq)
                    if ~any(any(any(isnan(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.pks)))) && any(any(any(isnan(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).peaks.nodes))))
                        cont = 0;
                    end
                end
            end

            if cont
                saveExcel_peaks(app.path, app.fn, app.list, app.details, app.thresh)
            else
                % display warning message
                waitfor(warndlg('One or more ABRs have peaks but not nodes, must have both inorder to save'))
            end

        end

        % Button pushed function: saveButton_mat
        function saveButton_matPushed(app, event)
            
            % select folder and file name to save to
            [file,path] = uiputfile(fullfile(app.path, [app.fn, '.mat']));

            % catch to make sure the user choose a file
            if sum(file) ~= 0
                
                saveName = fullfile(path, file);

                details = app.details;
                thresh = app.thresh;

                save(saveName, 'details', 'thresh', '-v7.3')
            end
            
        end

        % Button pushed function: saveButton_Image
        function saveButton_ImagePushed(app, event)
            
            % define types of files the figure can be saved as
            type = {'*.png'; '*.jpg'; '*.tif'; '*.svg'; '*.pdf'; '*.fig'};

            % select folder and file name to save to
            [file,path,idx] = uiputfile(type, 'File Selection', fullfile(app.path, app.fn));

            % catch to make sure the user choose a file
            if sum(file) == 0
                return
            end

            % make saveName
            saveName = fullfile(path, file);

            saveImage(saveName, app.UIAxes, type{idx})

        end

        % Value changed function: ScaleBarDropDown
        function ScaleBarDropDownValueChanged(app, event)
            value = app.ScaleBarDropDown.Value;

            switch value
                case '50 mV'
                    app.sep = 1e-1;
                case '5 mV'
                    app.sep = 1e-2;
                case '500 uV'
                    app.sep = 1e-3;
                case '50 uV'
                    app.sep = 1e-4;
                case '5 uV'
                    app.sep = 1e-5;
                case '500 nV'
                    app.sep = 1e-6;
                case '50 nV'
                    app.sep = 1e-7;
                case '5 nV'
                    app.sep = 1e-8;
            end

            replot(app);

        end

        % Value changed function: ShowScaleBarCheckBox
        function ShowScaleBarCheckBoxValueChanged(app, event)
            
            replot(app);
            
        end

        % Value changed function: NormHeightCheckBox
        function NormHeightCheckBoxValueChanged(app, event)
            value = app.NormHeightCheckBox.Value;
            app.norm = value;

            if app.norm
                app.scale = normalizeScale(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform * app.SystemGainEditField.Value, app.sep);
            else
                app.scale = 1;
            end

            replot(app);

        end

        % Button pushed function: AutoThresholdButton
        function AutoThresholdButtonPushed(app, event)
            
            % update the autothresh boolean
            app.autothresh = 1;

            % open waitbar window
            w = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Calculating Thresholds');

            % setup thresholds structure
            app = autoABRThresh(app, w);

            % display warning message
            waitfor(warndlg('Auto Thresholding complete. Go through and double check thresholds are correct'))

            replot(app);

            % close waitbar window
            close(w)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           
            % open dialog box to ask if files want to be saved
            answer = questdlg('Would you to save?', 'answer', 'Yes','No', 'Yes');
            
            if ~isempty(answer)
                if strcmp(answer, 'Yes')
                    % select folder and file name to save to
                    [file,path] = uiputfile(fullfile(app.path, [app.fn, '.mat']));

                    % catch to make sure the user choose a file
                    if sum(file) ~= 0

                        saveName = fullfile(path, file);

                        details = app.details;
                        thresh = app.thresh;

                        save(saveName, 'details', 'thresh', '-v7.3')
                    else
                        return
                    end
                end
                delete(app)
            end
                           
        end

        % Button pushed function: return_button
        function return_buttonButtonPushed(app, event)
            
            % open dialog box to ask if files want to be saved
            answer = questdlg('Would you to save?', 'answer', 'Yes','No', 'Yes');
            
            if ~isempty(answer)
                if strcmp(answer, 'Yes')
                    % select folder and file name to save to
                    [file,path] = uiputfile(fullfile(app.path, [app.fn, '.mat']));

                    % catch to make sure the user choose a file
                    if sum(file) ~= 0

                        saveName = fullfile(path, file);

                        details = app.details;
                        thresh = app.thresh;

                        save(saveName, 'details', 'thresh', '-v7.3')
                    else
                        return
                    end
                end
                delete(app)

                % reopen AcousticPlotterStartGUI
                app = AcousticPlotterStartGUI;
                % call app
                startfuncmaster(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProjectFNLabel = uilabel(app.UIFigure);
            app.ProjectFNLabel.Text = 'Project FN';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.InformationTextArea.Editable = 'off';
            app.InformationTextArea.Value = {'information'};
            app.FreqListBox = uilistbox(app.UIFigure);
            app.FreqListBox.ValueChangedFcn = createCallbackFcn(app, @FreqListBoxValueChanged, true);
            app.FrequenciesLabel = uilabel(app.UIFigure);
            app.FrequenciesLabel.Text = 'Frequencies';
            app.MouseListBox = uilistbox(app.UIFigure);
            app.MouseListBox.ValueChangedFcn = createCallbackFcn(app, @MouseListBoxValueChanged, true);
            app.MouseNameLabel = uilabel(app.UIFigure);
            app.MouseNameLabel.Text = 'Mouse Name';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold = ';
            app.ThreshSummaryButton = uibutton(app.UIFigure, 'push');
            app.ThreshSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ThreshSummaryButtonPushed, true);
            app.ThreshSummaryButton.Text = 'Thresh Summary';
            app.saveButton_mat = uibutton(app.UIFigure, 'push');
            app.saveButton_mat.ButtonPushedFcn = createCallbackFcn(app, @saveButton_matPushed, true);
            app.saveButton_mat.Text = 'Save Mat';
            app.saveButton_xlsx = uibutton(app.UIFigure, 'push');
            app.saveButton_xlsx.ButtonPushedFcn = createCallbackFcn(app, @saveButton_xlsxPushed, true);
            app.saveButton_xlsx.Text = 'Save Excel';
            app.HighEditFieldLabel = uilabel(app.UIFigure);
            app.HighEditFieldLabel.Visible = 'off';
            app.HighEditFieldLabel.Text = 'High Cut';
            app.HighFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighFilterEditField.ValueChangedFcn = createCallbackFcn(app, @HighFilterEditFieldValueChanged, true);
            app.HighFilterEditField.Visible = 'off';
            app.HighFilterEditField.Value = 3000;
            app.LowEditFieldLabel = uilabel(app.UIFigure);
            app.LowEditFieldLabel.Visible = 'off';
            app.LowEditFieldLabel.Text = 'Low Cut';
            app.LowFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowFilterEditField.ValueChangedFcn = createCallbackFcn(app, @LowFilterEditFieldValueChanged, true);
            app.LowFilterEditField.Visible = 'off';
            app.LowFilterEditField.Value = 30;
            app.AddButterworthFilterLabel_2 = uilabel(app.UIFigure);
            app.AddButterworthFilterLabel_2.Text = 'Add FIR Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'None', 'HighPass', 'LowPass', 'BandPass'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'None';
            app.saveButton_Image = uibutton(app.UIFigure, 'push');
            app.saveButton_Image.ButtonPushedFcn = createCallbackFcn(app, @saveButton_ImagePushed, true);
            app.saveButton_Image.Text = 'Save Image';
            app.AutoThresholdButton = uibutton(app.UIFigure, 'push');
            app.AutoThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AutoThresholdButtonPushed, true);
            app.AutoThresholdButton.Text = 'Auto Threshold';
            app.saveButton_peaks = uibutton(app.UIFigure, 'push');
            app.saveButton_peaks.ButtonPushedFcn = createCallbackFcn(app, @saveButton_peaksPushed, true);
            app.saveButton_peaks.Visible = 'off';
            app.saveButton_peaks.Text = 'Export Peaks';
            app.return_button = uibutton(app.UIFigure, 'push');
            app.return_button.ButtonPushedFcn = createCallbackFcn(app, @return_buttonButtonPushed, true);
            app.return_button.Text = '<<< Return ';
            app.ScaleBarPanel = uipanel(app.UIFigure);
            app.ScaleBarPanel.Title = 'Scale Bar';
            app.ShowScaleBarCheckBox = uicheckbox(app.ScaleBarPanel);
            app.ShowScaleBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowScaleBarCheckBoxValueChanged, true);
            app.ShowScaleBarCheckBox.Text = 'Show Scale Bar';
            app.ShowScaleBarCheckBox.Value = true;
            app.ScaleBarDropDown = uidropdown(app.ScaleBarPanel);
            app.ScaleBarDropDown.Items = {'50 mV', '5 mV', '500 uV', '50 uV', '5 uV', '500 nV', '50 nV', '5 nV'};
            app.ScaleBarDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleBarDropDownValueChanged, true);
            app.ScaleBarDropDown.Value = '500 nV';
            app.NormHeightCheckBox = uicheckbox(app.UIFigure);
            app.NormHeightCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormHeightCheckBoxValueChanged, true);
            app.NormHeightCheckBox.Text = 'Norm Height';
            app.SystemGainEditFieldLabel = uilabel(app.UIFigure);
            app.SystemGainEditFieldLabel.Text = 'System Gain';
            app.SystemGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemGainEditField.ValueChangedFcn = createCallbackFcn(app, @SystemGainEditFieldValueChanged, true);
            app.SystemGainEditField.Value = 1;
            app.InvertWaveformCheckBox = uicheckbox(app.UIFigure);
            app.InvertWaveformCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertWaveformCheckBoxValueChanged, true);
            app.InvertWaveformCheckBox.Text = 'Invert Waveform';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotABRs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end