classdef saveSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveWithFilterCheckBox  matlab.ui.control.CheckBox
        ListBox                 matlab.ui.control.ListBox
        SelectAllCheckBox       matlab.ui.control.CheckBox
        SelectButton            matlab.ui.control.Button
        FNLabel                 matlab.ui.control.Label
        holdCTRLtohighlightmultipleLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        list = {}; % cell of all groups to be displayed
    end
    
    properties (Access = public)
        selected = []; % array for determining what groups have been selected and make accessible to other functions
        filt = 0; % save with or without filter, default without
    end
    
    methods (Access = public)

        function startfuncmaster(app, list)


            % load app.list
            app.list = list;

            % enable multiselect
            app.ListBox.Multiselect = 'on';

            % propogate listbox
            app.ListBox.Items = app.list;

            % select all items in listbox
            app.ListBox.Value = app.list;

            % make code start waiting for app to run
            uiwait(app.UIFigure)

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            value = app.ListBox.Value;
            
            % if all items are selected, make checkbox true
            if length(value) == length(app.ListBox.Items)
                app.SelectAllCheckBox.Value = true;
            else
                app.SelectAllCheckBox.Value = false;
            end
            
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            
            value = app.SelectAllCheckBox.Value;
            
            % if select all checkbox true, select everything otherwise deselect everything
            if value
                app.ListBox.Value = app.list;
            else
                app.ListBox.Value = {};
            end
            
        end

        % Value changed function: SaveWithFilterCheckBox
        function SaveWithFilterCheckBoxValueChanged(app, event)
            
            app.filt = app.SaveWithFilterCheckBox.Value;

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)

            % find selected items in ListBox
            for i = 1:length(app.ListBox.Value)
                idx = find(contains(app.list, app.ListBox.Value{i}));
                if length(idx) > 1
                    idx = idx(1);
                end
                app.selected(i) = idx;
            end
            app.selected = sort(app.selected);

            % result app when select button pressed
            uiresume(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.holdCTRLtohighlightmultipleLabel = uilabel(app.UIFigure);
            app.holdCTRLtohighlightmultipleLabel.Text = '(hold CTRL to highlight multiple)';
            app.FNLabel = uilabel(app.UIFigure);
            app.FNLabel.Text = 'Select Animals to be Saved';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectAllCheckBox = uicheckbox(app.UIFigure);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.SelectAllCheckBox.Value = true;
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.SaveWithFilterCheckBox = uicheckbox(app.UIFigure);
            app.SaveWithFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveWithFilterCheckBoxValueChanged, true);
            app.SaveWithFilterCheckBox.Text = 'Save With Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saveSelect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end